classdef volt_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VoltAppUIFigure            matlab.ui.Figure
        LoadButton                 matlab.ui.control.Button
        FindfolderButton           matlab.ui.control.Button
        openwindowsforHPfilterdetrendedandsnippetsTextArea  matlab.ui.control.TextArea
        openwindowsforHPfilterdetrendedandsnippetsTextAreaLabel  matlab.ui.control.Label
        DetrendedFTextArea         matlab.ui.control.TextArea
        DetrendedFTextAreaLabel    matlab.ui.control.Label
        SpikesSwitch               matlab.ui.control.ToggleSwitch
        SNRTextArea_2              matlab.ui.control.TextArea
        SNRTextArea_2Label         matlab.ui.control.Label
        DFFTextAreaLabel_2         matlab.ui.control.Label
        imageTextArea              matlab.ui.control.TextArea
        imageTextAreaLabel         matlab.ui.control.Label
        DFFTextArea                matlab.ui.control.TextArea
        DFFTextAreaLabel           matlab.ui.control.Label
        SNRTextArea                matlab.ui.control.TextArea
        SNRTextAreaLabel           matlab.ui.control.Label
        SaveanalysisButton         matlab.ui.control.Button
        RunanalysisButton          matlab.ui.control.Button
        Tree                       matlab.ui.container.CheckBoxTree
        allROIsNode                matlab.ui.container.TreeNode
        Node2                      matlab.ui.container.TreeNode
        Node3                      matlab.ui.container.TreeNode
        Node4                      matlab.ui.container.TreeNode
        FolderpathEditField        matlab.ui.control.EditField
        FolderpathEditFieldLabel   matlab.ui.control.Label
        validtimerangeSlider       matlab.ui.control.RangeSlider
        validtimerangeSliderLabel  matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes_Fraw                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        datadir   % directory of data
        titlestr  % name of data
        dat       % structure with original data
        F_proc    % structure for processed data
        plot_range % shading area for valid timepoints
    end
    
    methods (Access = private)
        
        function startupFcn(app)
            app.VoltAppUIFigure.Name = 'VoltApp';
            addpath(genpath('/net/engnas/Users/s/s/sshayk/My Documents/MATLAB/analyze_voltage'))
            addpath(genpath('\\ad\eng\users\s\s\sshayk\My Documents\MATLAB\analyze_voltage'))

            addpath '/net/engnas/Users/s/s/sshayk/My Documents/MATLAB/utilities'
            addpath '\\engnas.bu.edu\users\s\s\sshayk\My Documents\MATLAB\utilities'
            app.UIAxes_Fraw.Visible = 'off';
            app.validtimerangeSlider.Visible = 'off';
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FolderpathEditField
        function FolderpathEditFieldValueChanged(app, event)
            app.datadir = app.FolderpathEditField.Value;
            
        end

        % Button pushed function: FindfolderButton
        function FindfolderButtonPushed(app, event)
            app.datadir = uigetdir;
            app.FolderpathEditField.Value = app.datadir;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            datastr = strrep(app.datadir,'\\ad\eng\research\','U:\');

            strparts = strsplit(datastr,{'/','\'});
            app.titlestr = [strparts{end-2},'/',strparts{end-3},'/',strparts{end-1},'/',strparts{end}(10:end)];
            app.dat.signal = load(fullfile(datastr,'signal.mat'));
            app.dat.rois = load(fullfile(datastr,'rois.mat'));
            app.dat.nframes = size(app.dat.signal.tr,2);
            app.dat.params.fs = 800;
            app.dat.tvec = (1:app.dat.nframes)*(1/app.dat.params.fs);

            % get F values
            app.F_proc = process_voltage(app.dat.signal.tr);
            [app.F_proc.F_AP, app.F_proc.params_AP] = calc_APs(app.F_proc.F_det);
            % get spikes
            [app.F_proc.t_s, app.F_proc.parameters_kspikes] = get_spike_locations(app.F_proc.F_det, app.F_proc.F_AP, app.F_proc.N_f, app.F_proc.dF_ur, app.F_proc.dF_dr); 
            % get Vm and other baseline calculations
            [app.F_proc.F_sub, app.F_proc.F_nospike, app.F_proc.F_0, app.F_proc.params_baselines] = get_subthreshold_trace(app.dat.signal.tr, app.F_proc.t_s);

            app.dat.params.k_valid = true(size(app.dat.tvec));

            app.UIAxes_Fraw.Visible = 'on';
            app.validtimerangeSlider.Visible = 'on';
            app.validtimerangeSlider.Limits = [0 app.dat.tvec(end)];
            app.validtimerangeSlider.Value = [0 app.dat.tvec(end)];
            plot(app.UIAxes_Fraw,app.dat.tvec,-app.dat.signal.tr), 
            xlabel(app.UIAxes_Fraw,'time (s)'), ylabel(app.UIAxes_Fraw,'-F')
            title(app.UIAxes_Fraw,app.titlestr)
            app.plot_range = app.validtimerangeSlider.Value;

        end

        % Value changed function: validtimerangeSlider
        function validtimerangeSliderValueChanged(app, event)
            value = app.validtimerangeSlider.Value;
            kval = true(size(app.dat.tvec));

            kval(tvec<=value(1)) = 0;
            kval(tvec>=value(2)) = 0;

            app.dat.params.k_valid = kval;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VoltAppUIFigure = uifigure('Visible', 'off');
            app.VoltAppUIFigure.Name = 'VoltApp';
            app.UIAxes_Fraw = uiaxes(app.VoltAppUIFigure);
            title(app.UIAxes_Fraw, 'Raw F traces')
            xlabel(app.UIAxes_Fraw, 'time (s)')
            ylabel(app.UIAxes_Fraw, '-F')
            zlabel(app.UIAxes_Fraw, 'Z')
            app.UIAxes_Fraw.Visible = 'off';
            app.UIAxes2 = uiaxes(app.VoltAppUIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.validtimerangeSliderLabel = uilabel(app.VoltAppUIFigure);
            app.validtimerangeSliderLabel.Text = 'valid time range';
            app.validtimerangeSlider = uislider(app.VoltAppUIFigure, 'range');
            app.validtimerangeSlider.Limits = [0 1];
            app.validtimerangeSlider.ValueChangedFcn = createCallbackFcn(app, @validtimerangeSliderValueChanged, true);
            app.validtimerangeSlider.Value = [0 1];
            app.FolderpathEditFieldLabel = uilabel(app.VoltAppUIFigure);
            app.FolderpathEditFieldLabel.Text = 'Folder path';
            app.FolderpathEditField = uieditfield(app.VoltAppUIFigure, 'text');
            app.FolderpathEditField.ValueChangedFcn = createCallbackFcn(app, @FolderpathEditFieldValueChanged, true);
            app.Tree = uitree(app.VoltAppUIFigure, 'checkbox');
            app.allROIsNode = uitreenode(app.Tree);
            app.allROIsNode.Text = 'all ROIs';
            app.Node2 = uitreenode(app.allROIsNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.allROIsNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.allROIsNode);
            app.Node4.Text = 'Node4';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.Node2, app.Node3, app.Node4, app.allROIsNode];
            app.RunanalysisButton = uibutton(app.VoltAppUIFigure, 'push');
            app.RunanalysisButton.Text = 'Run analysis';
            app.SaveanalysisButton = uibutton(app.VoltAppUIFigure, 'push');
            app.SaveanalysisButton.Text = 'Save analysis';
            app.SNRTextAreaLabel = uilabel(app.VoltAppUIFigure);
            app.SNRTextAreaLabel.Text = 'SNR';
            app.SNRTextArea = uitextarea(app.VoltAppUIFigure);
            app.DFFTextAreaLabel = uilabel(app.VoltAppUIFigure);
            app.DFFTextAreaLabel.Text = 'DF/F';
            app.DFFTextArea = uitextarea(app.VoltAppUIFigure);
            app.imageTextAreaLabel = uilabel(app.VoltAppUIFigure);
            app.imageTextAreaLabel.Text = 'image';
            app.imageTextArea = uitextarea(app.VoltAppUIFigure);
            app.DFFTextAreaLabel_2 = uilabel(app.VoltAppUIFigure);
            app.DFFTextAreaLabel_2.Text = 'DF/F';
            app.SNRTextArea_2Label = uilabel(app.VoltAppUIFigure);
            app.SNRTextArea_2Label.Text = 'SNR';
            app.SNRTextArea_2 = uitextarea(app.VoltAppUIFigure);
            app.SpikesSwitch = uiswitch(app.VoltAppUIFigure, 'toggle');
            app.SpikesSwitch.Items = {'', 'Spikes on '};
            app.SpikesSwitch.Value = '';
            app.DetrendedFTextAreaLabel = uilabel(app.VoltAppUIFigure);
            app.DetrendedFTextAreaLabel.Text = 'Detrended F';
            app.DetrendedFTextArea = uitextarea(app.VoltAppUIFigure);
            app.openwindowsforHPfilterdetrendedandsnippetsTextAreaLabel = uilabel(app.VoltAppUIFigure);
            app.openwindowsforHPfilterdetrendedandsnippetsTextAreaLabel.Text = 'open windows for HP filter, detrended, and snippets  ';
            app.openwindowsforHPfilterdetrendedandsnippetsTextArea = uitextarea(app.VoltAppUIFigure);
            app.FindfolderButton = uibutton(app.VoltAppUIFigure, 'push');
            app.FindfolderButton.ButtonPushedFcn = createCallbackFcn(app, @FindfolderButtonPushed, true);
            app.FindfolderButton.Text = 'Find folder';
            app.LoadButton = uibutton(app.VoltAppUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.VoltAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = volt_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VoltAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VoltAppUIFigure)
        end
    end
end